classdef Main < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        SARMEDUIFigure                 matlab.ui.Figure
        TabGroup                       matlab.ui.container.TabGroup
        ENCRYPTIONTab                  matlab.ui.container.Tab
        GridLayout                     matlab.ui.container.GridLayout
        Start_Enc_Image                matlab.ui.control.Image
        EncStatus_TextArea             matlab.ui.control.TextArea
        EncryptionStatusTextAreaLabel  matlab.ui.control.Label
        Click_toLoadImage_Label        matlab.ui.control.Label
        Original_Image                 matlab.ui.control.Image
        Button_Enter_inital_Key        matlab.ui.control.Button
        Initial_Key_EditField          matlab.ui.control.EditField
        Save_EncryptedImages_Label     matlab.ui.control.Label
        Click_toEncryptImage_Label     matlab.ui.control.Label
        Save_Encryptedimgaes_Button    matlab.ui.control.Button
        DecryptionKey_Label            matlab.ui.control.Label
        DecryptionKey_TextArea         matlab.ui.control.EditField
        ClearEncryptionTab_Button      matlab.ui.control.Button
        MainDisp_Axes                  matlab.ui.control.UIAxes
        FirstDisp_Axes                 matlab.ui.control.UIAxes
        SecondDisp_Axes                matlab.ui.control.UIAxes
        DECRYPTIONTab                  matlab.ui.container.Tab
        GridLayout2                    matlab.ui.container.GridLayout
        DecStatus_TextArea             matlab.ui.control.TextArea
        LoadImagestoDecrypt_Label      matlab.ui.control.Label
        loaded_Encryptedimges_Image    matlab.ui.control.Image
        EnterKeyButton                 matlab.ui.control.Button
        DecryptionKey                  matlab.ui.control.EditField
        SaveDecryptedImage_Label       matlab.ui.control.Label
        SaveDecImage_Button            matlab.ui.control.Button
        CheckQuality_Button            matlab.ui.control.Button
        CheckDecryptedImageQualityLabel  matlab.ui.control.Label
        ClearDecryptionTab             matlab.ui.control.Button
        DecDisp_Axes                   matlab.ui.control.UIAxes
        SecondEncDisp_Axes             matlab.ui.control.UIAxes
        FirstEncDisp_Axes              matlab.ui.control.UIAxes
    end

    properties (Access = private)
        loadedImage; % Original image
        initikey; % Initial key 
        firstimg; secondimg; %Encrypted Images
        encimg1; encimg2; % Encrypted Images for Decryption Process
        decimg; % Decrypted image
    end
%<========================================================================>     
    methods (Access = private)
        function  ClearAllButton(app)
          app.Button_Enter_inital_Key.Visible="off";
          app.Initial_Key_EditField.Value='';
          app.Initial_Key_EditField.Visible="off";
          app.Click_toEncryptImage_Label.Visible='off';
          app.Start_Enc_Image.Visible="off";
          app.Save_EncryptedImages_Label.Visible="off";
          app.Save_Encryptedimgaes_Button.Visible='off';
          app.DecryptionKey_TextArea.Visible="off";
          app.DecryptionKey_Label.Visible='off';
          app.EncStatus_TextArea.Visible="off";
          app.EncryptionStatusTextAreaLabel.Visible="off";
          
        end
%<========================================================================>
        function  ClearAllAxes(app)
         cla(app.MainDisp_Axes);
         app.MainDisp_Axes.Title.Visible="off";
         cla(app.FirstDisp_Axes);
         app.FirstDisp_Axes.Title.Visible="off";
         cla(app.SecondDisp_Axes);
         app.SecondDisp_Axes.Title.Visible="off";  
        end    
%<========================================================================>
function ShowEncUI(app,deckey)
         app.FirstDisp_Axes.Title.String="First Encrypted Image";
         imshow(app.firstimg,'Parent',app.FirstDisp_Axes);
         app.SecondDisp_Axes.Title.String="Second Encrypted Image";
         imshow(app.secondimg,'Parent',app.SecondDisp_Axes);
         app.DecryptionKey_TextArea.Value=deckey;
         app.DecryptionKey_TextArea.Visible='on';
         app.DecryptionKey_Label.Visible="on";
         app.Save_Encryptedimgaes_Button.Visible='on';
         app.Save_EncryptedImages_Label.Visible='on';
end
%<========================================================================>        
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
app.SARMEDUIFigure.WindowState="maximized";
        end

        % Image clicked function: Original_Image
        function Load_Original(app, event)
%<=====================Read Image for encryption======================>
try
[fileName,pathName]=Read_Path();
%<===========Display Image and enable Adding Key Button===============>
image=imread(fullfile(pathName,fileName));
app.MainDisp_Axes.Title.String='Main Image';
imshow(image,'Parent',app.MainDisp_Axes);
app.loadedImage=image; 
app.Button_Enter_inital_Key.Visible="on";
app.Initial_Key_EditField.Visible="on";
%<====================================================================>
catch ME
      errordlg('No Image have been selected','Error occured','modal');
      app.ClearAllAxes;
      app.loadedImage=[];
end
        end

        % Button pushed function: Button_Enter_inital_Key
        function Enter_inital_Key_Button(app, event)
%<=========check if there is any letters in initial key string========>
app.initikey=app.Initial_Key_EditField.Value;
%<====================================================================>               
if length(app.initikey)== 16
   app.Start_Enc_Image.Visible='on';
   app.Click_toEncryptImage_Label.Visible="on";         
else
   errordlg("Initial key must be 16 values long",'Error');
   return;
end       
        end

        % Image clicked function: Start_Enc_Image
        function Perform_Encryption(app, event)
%<=======================Encryption Algorithm==========================>
try
tic;
   [app.firstimg,app.secondimg,deckey]=...
   Bio_Chaotic_Encryption(app.loadedImage,app.initikey); 
time=toc;
%<=======================Displaying Buttons and Key=======================>
app.ShowEncUI(deckey);
app.EncStatus_TextArea.Visible="on";
app.EncryptionStatusTextAreaLabel.Visible="on";
app.EncStatus_TextArea.Value=['***Analyzing Image Security***',newline];
%<=======================Analyze Entropy==================================>
results = '|___Analysis Results___|';
results = [results newline AnalEntropy(app.firstimg, app.secondimg)];
 app.EncStatus_TextArea.Value = results;
 drawnow;
%<=======================Calculate NPCR===================================>
results=...
    [results newline AnalNPCR(app.loadedImage,app.firstimg,app.secondimg)];
app.EncStatus_TextArea.Value=results;
drawnow;
%<=======================Calculate UACI===================================>
results=...
    [results newline AnalUACI(app.loadedImage,app.firstimg,app.secondimg)];
app.EncStatus_TextArea.Value=results;
drawnow;
%<=======================Calculate Correlation =======================>
results = [results newline AnalCorr(app.firstimg, app.secondimg)];
 app.EncStatus_TextArea.Value = results;
 drawnow;
%<=======================Encryption Time==================================>
results=[results newline 'Encryption Time:' num2str(time)];
app.EncStatus_TextArea.Value=results;
drawnow;
%<========================================================================>
app.ClearEncryptionTab_Button.Visible="on";
catch me
    errordlg("Something went wrong try again",'Error','modal');
end

        end

        % Button pushed function: Save_Encryptedimgaes_Button
        function Save_Encrypted_Images(app, event)
try
%<============================Saving First Child==========================>
 timestamp = datestr(now, 'yyyymmdd_HHMMSS');
 imageName = sprintf('encrypted_1_%s_%04d', timestamp, randi([0, 9999]));
 SaveImage(imageName,app.firstimg);
 %<============================Saving Second Child========================>
 imageName = sprintf('encrypted_2_%s_%04d', timestamp, randi([0, 9999]));
 SaveImage(imageName,app.secondimg);
%<=================================End====================================>
catch SaveEncError
    errordlg('Save operation failed try again','Error occurred','modal');
end
 
        end

        % Button pushed function: ClearEncryptionTab_Button
        function Clear_EncryptionTab(app, event)
            app.ClearAllAxes;
            app.ClearAllButton;
            app.ClearEncryptionTab_Button.Visible="off";
        end

        % Image clicked function: loaded_Encryptedimges_Image
        function loaded_Encryptedimges_ImageClicked(app, event)
%<===========================Read first image=============================>
try
[fileName,pathName]=Read_Path();
app.encimg1 =imread(fullfile(pathName,fileName));
app.FirstEncDisp_Axes.Title.String='First Encrypted Image';
imshow(app.encimg1,'Parent',app.FirstEncDisp_Axes);
%<=======================Read second image================================>
[fileName,pathName]=Read_Path();
app.encimg2 =imread(fullfile(pathName,fileName));
app.SecondEncDisp_Axes.Title.String='Second Encrypted Image';
imshow(app.encimg2,'Parent',app.SecondEncDisp_Axes);
%<========================================================================>
app.EnterKeyButton.Visible="on";
app.DecryptionKey.Visible="on";
catch decloaderror
    errordlg('Load operation failed or was cancelled try again','Error occured','modal');
end
        end

        % Button pushed function: EnterKeyButton
        function EnterKeyButtonPushed(app, event)
key=app.DecryptionKey.Value;
if length(key)>=128
[app.encimg1,app.encimg2,app.initikey]=ExtractKey(key,app.encimg1,app.encimg2);                                 
%<=========================Decryption Algorithm===========================>
app.decimg=Decrypt_image(app.encimg1,app.encimg2,app.initikey);
app.DecDisp_Axes.Title.String='Decrypted Image';
imshow(app.decimg,'Parent',app.DecDisp_Axes);
%<========================================================================>
app.SaveDecryptedImage_Label.Visible="on";
app.SaveDecImage_Button.Visible="on";
app.CheckDecryptedImageQualityLabel.Visible='on';
app.CheckQuality_Button.Visible='on';
else
    errordlg(' Invalid Decryption Key','Error Key');
end

        end

        % Button pushed function: CheckQuality_Button
        function CheckQuality_ButtonButtonPushed(app, event)
try
[fileName,pathName]=Read_Path();
decimage =imread(fullfile(pathName,fileName));
[fileName,pathName]=Read_Path();
origiimg =imread(fullfile(pathName,fileName));
%<=======================Calculate PSNR===============================>
app.DecStatus_TextArea.Visible='on';
app.DecS
results = '|___Analyze Results___|';
results = [results newline CalcPSNR( origiimg,decimage)];
app.DecStatus_TextArea.Value = results;
 drawnow;
%<===================Calculate IrregularDeviation=====================>
results = [ results newline CalcSTD(origiimg, decimage)];
app.DecStatus_TextArea.Value = results;
drawnow;
%<=======================Calculate MEAN===============================>
results=[results newline CalcMEAN(origiimg, decimage)];
app.DecStatus_TextArea.Value = results;
drawnow;
%<=======================Calculate MSE================================>
results=[results newline CalcMSE(origiimg, decimage)];
app.DecStatus_TextArea.Value = results;
drawnow;
%<====================================================================>
app.ClearDecryptionTab.Visible='on';
catch StatusError
    errordlg('An error occured try again','Error occurred','modal');
end
        end

        % Button pushed function: SaveDecImage_Button
        function SaveDecImage_ButtonButtonPushed(app, event)
 try
 timestamp = datestr(now, 'yyyymmdd_HHMMSS');
 imageName = sprintf('Decrypted_%s_%04d', timestamp, randi([0, 9999]));
 SaveImage(imageName,app.decimg);     
 catch SaveError
     errordlg('Save operation failed or was canclled try again','Error occurred','modal');
 end  
        end

        % Button pushed function: ClearDecryptionTab
        function ClearDecryptionTabButtonPushed(app, event)
            app.FirstEncDisp_Axes.Visible="off";
            app.FirstEncDisp_Axes.Title.Visible="off";
            cla(app.FirstEncDisp_Axes);
            app.SecondEncDisp_Axes.Visible="off";
            app.SecondEncDisp_Axes.Title.Visible="off";
            cla(app.SecondEncDisp_Axes);
            app.DecDisp_Axes.Visible="off";
            app.DecDisp_Axes.Title.Visible="off";
            cla(app.DecDisp_Axes);
%<========================================================================>
            app.decimg=[];
            app.encimg1=[];
            app.encimg2=[];
%<========================================================================>
            app.EnterKeyButton.Visible="off";
            app.DecryptionKey.Visible="off";
            app.DecryptionKey.Value='';
            app.SaveDecryptedImage_Label.Visible="off";
            app.SaveDecImage_Button.Visible='off';
            app.CheckQuality_Button.Visible="off";
            app.CheckDecryptedImageQualityLabel.Visible="off";
            app.DecStatus_TextArea.Visible="off";
            app.DecStatus_TextArea.Value="";
            app.ClearDecryptionTab.Visible="off";
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.SARMEDUIFigure = uifigure('Visible', 'off');
            app.SARMEDUIFigure.Name = 'SARMED';
            app.SARMEDUIFigure.Icon = fullfile(pathToMLAPP, 'appIcon.png');
            app.TabGroup = uitabgroup(app.SARMEDUIFigure);
            app.ENCRYPTIONTab = uitab(app.TabGroup);
            app.ENCRYPTIONTab.Title = 'ENCRYPTION';
            app.GridLayout = uigridlayout(app.ENCRYPTIONTab);
            app.SecondDisp_Axes = uiaxes(app.GridLayout);
            app.SecondDisp_Axes.XTick = [];
            app.SecondDisp_Axes.XTickLabel = '';
            app.SecondDisp_Axes.YTick = [];
            app.SecondDisp_Axes.YTickLabel = '';
            app.SecondDisp_Axes.Visible = 'off';
            app.FirstDisp_Axes = uiaxes(app.GridLayout);
            app.FirstDisp_Axes.XTick = [];
            app.FirstDisp_Axes.XTickLabel = '';
            app.FirstDisp_Axes.YTick = [];
            app.FirstDisp_Axes.YTickLabel = '';
            app.FirstDisp_Axes.Visible = 'off';
            app.MainDisp_Axes = uiaxes(app.GridLayout);
            app.MainDisp_Axes.XTick = [];
            app.MainDisp_Axes.XTickLabel = '';
            app.MainDisp_Axes.YTick = [];
            app.MainDisp_Axes.YTickLabel = '';
            app.MainDisp_Axes.Visible = 'off';
            app.ClearEncryptionTab_Button = uibutton(app.GridLayout, 'push');
            app.ClearEncryptionTab_Button.ButtonPushedFcn = createCallbackFcn(app, @Clear_EncryptionTab, true);
            app.ClearEncryptionTab_Button.Visible = 'off';
            app.ClearEncryptionTab_Button.Text = 'CLEAR ALL';
            app.DecryptionKey_TextArea = uieditfield(app.GridLayout, 'text');
            app.DecryptionKey_TextArea.CharacterLimits = [1 Inf];
            app.DecryptionKey_TextArea.Editable = 'off';
            app.DecryptionKey_TextArea.Visible = 'off';
            app.DecryptionKey_Label = uilabel(app.GridLayout);
            app.DecryptionKey_Label.Visible = 'off';
            app.DecryptionKey_Label.Text = 'Decryption Key';
            app.Save_Encryptedimgaes_Button = uibutton(app.GridLayout, 'push');
            app.Save_Encryptedimgaes_Button.ButtonPushedFcn = createCallbackFcn(app, @Save_Encrypted_Images, true);
            app.Save_Encryptedimgaes_Button.Visible = 'off';
            app.Save_Encryptedimgaes_Button.Text = 'Save As';
            app.Click_toEncryptImage_Label = uilabel(app.GridLayout);
            app.Click_toEncryptImage_Label.Visible = 'off';
            app.Click_toEncryptImage_Label.Text = '3. Click to Encrypt Image';
            app.Save_EncryptedImages_Label = uilabel(app.GridLayout);
            app.Save_EncryptedImages_Label.Visible = 'off';
            app.Save_EncryptedImages_Label.Text = '4.Save Encrypted Images';
            app.Initial_Key_EditField = uieditfield(app.GridLayout, 'text');
            app.Initial_Key_EditField.CharacterLimits = [0 16];
            app.Initial_Key_EditField.Visible = 'off';
            app.Button_Enter_inital_Key = uibutton(app.GridLayout, 'push');
            app.Button_Enter_inital_Key.ButtonPushedFcn = createCallbackFcn(app, @Enter_inital_Key_Button, true);
            app.Button_Enter_inital_Key.Visible = 'off';
            app.Button_Enter_inital_Key.Text = '2.Enter Initial Key';
            app.Original_Image = uiimage(app.GridLayout);
            app.Original_Image.ImageClickedFcn = createCallbackFcn(app, @Load_Original, true);
            app.Original_Image.ImageSource = fullfile(pathToMLAPP, 'load.png');
            app.Click_toLoadImage_Label = uilabel(app.GridLayout);
            app.Click_toLoadImage_Label.Text = '1.Cilck to Load Image';
            app.EncryptionStatusTextAreaLabel = uilabel(app.GridLayout);
            app.EncryptionStatusTextAreaLabel.Visible = 'off';
            app.EncryptionStatusTextAreaLabel.Text = 'Encryption Status';
            app.EncStatus_TextArea = uitextarea(app.GridLayout);
            app.EncStatus_TextArea.Editable = 'off';
            app.EncStatus_TextArea.Visible = 'off';
            app.Start_Enc_Image = uiimage(app.GridLayout);
            app.Start_Enc_Image.ImageClickedFcn = createCallbackFcn(app, @Perform_Encryption, true);
            app.Start_Enc_Image.Visible = 'off';
            app.Start_Enc_Image.ImageSource = fullfile(pathToMLAPP, 'encryption.png');
            app.DECRYPTIONTab = uitab(app.TabGroup);
            app.DECRYPTIONTab.Title = 'DECRYPTION';
            app.GridLayout2 = uigridlayout(app.DECRYPTIONTab);
            app.FirstEncDisp_Axes = uiaxes(app.GridLayout2);
            app.FirstEncDisp_Axes.XTick = [];
            app.FirstEncDisp_Axes.YTick = [];
            app.FirstEncDisp_Axes.Visible = 'off';
            app.SecondEncDisp_Axes = uiaxes(app.GridLayout2);
            app.SecondEncDisp_Axes.XTick = [];
            app.SecondEncDisp_Axes.YTick = [];
            app.SecondEncDisp_Axes.Visible = 'off';
            app.DecDisp_Axes = uiaxes(app.GridLayout2);
            app.DecDisp_Axes.XTick = [];
            app.DecDisp_Axes.YTick = [];
            app.DecDisp_Axes.Visible = 'off';
            app.ClearDecryptionTab = uibutton(app.GridLayout2, 'push');
            app.ClearDecryptionTab.ButtonPushedFcn = createCallbackFcn(app, @ClearDecryptionTabButtonPushed, true);
            app.ClearDecryptionTab.Visible = 'off';
            app.ClearDecryptionTab.Text = 'CLEAR ALL';
            app.CheckDecryptedImageQualityLabel = uilabel(app.GridLayout2);
            app.CheckDecryptedImageQualityLabel.Visible = 'off';
            app.CheckDecryptedImageQualityLabel.Text = '4.Check Decrypted Image Quality';
            app.CheckQuality_Button = uibutton(app.GridLayout2, 'push');
            app.CheckQuality_Button.ButtonPushedFcn = createCallbackFcn(app, @CheckQuality_ButtonButtonPushed, true);
            app.CheckQuality_Button.Visible = 'off';
            app.CheckQuality_Button.Text = 'Load images';
            app.SaveDecImage_Button = uibutton(app.GridLayout2, 'push');
            app.SaveDecImage_Button.ButtonPushedFcn = createCallbackFcn(app, @SaveDecImage_ButtonButtonPushed, true);
            app.SaveDecImage_Button.Visible = 'off';
            app.SaveDecImage_Button.Text = 'Save As';
            app.SaveDecryptedImage_Label = uilabel(app.GridLayout2);
            app.SaveDecryptedImage_Label.Visible = 'off';
            app.SaveDecryptedImage_Label.Text = '3.Save Decrypted Image';
            app.DecryptionKey = uieditfield(app.GridLayout2, 'text');
            app.DecryptionKey.Visible = 'off';
            app.EnterKeyButton = uibutton(app.GridLayout2, 'push');
            app.EnterKeyButton.ButtonPushedFcn = createCallbackFcn(app, @EnterKeyButtonPushed, true);
            app.EnterKeyButton.Visible = 'off';
            app.EnterKeyButton.Text = '2.Enter Key';
            app.loaded_Encryptedimges_Image = uiimage(app.GridLayout2);
            app.loaded_Encryptedimges_Image.ImageClickedFcn = createCallbackFcn(app, @loaded_Encryptedimges_ImageClicked, true);
            app.loaded_Encryptedimges_Image.ImageSource = 'load.png';
            app.LoadImagestoDecrypt_Label = uilabel(app.GridLayout2);
            app.LoadImagestoDecrypt_Label.Text = '1.Click to Load Images';
            app.DecStatus_TextArea = uitextarea(app.GridLayout2);
            app.DecStatus_TextArea.Editable = 'off';
            app.DecStatus_TextArea.Visible = 'off';
            % Show the figure after all components are created
            app.SARMEDUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Main

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.SARMEDUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.SARMEDUIFigure)
        end
    end
end